classdef MAS_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MASUIFigure            matlab.ui.Figure
        LoadparametersButton   matlab.ui.control.Button
        SaveparametersButton   matlab.ui.control.Button
        msgBox                 matlab.ui.control.TextArea
        RunButton              matlab.ui.control.Button
        DetectionandanalysisparametersPanel  matlab.ui.container.Panel
        CSlicesEditField       matlab.ui.control.NumericEditField
        MaximumsigmapxLabel_3  matlab.ui.control.Label
        ZSlicesEditField       matlab.ui.control.NumericEditField
        MaximumsigmapxLabel_2  matlab.ui.control.Label
        BinSizeEditField       matlab.ui.control.NumericEditField
        TimesliceminLabel_2    matlab.ui.control.Label
        TimeSliceEditField     matlab.ui.control.NumericEditField
        TimesliceminLabel      matlab.ui.control.Label
        NumSubUnitsPerCalibComplexEditField  matlab.ui.control.NumericEditField
        Labelingeffeciency     matlab.ui.control.Label
        AnalyzeSwitch          matlab.ui.control.Switch
        AnalyzeLabel           matlab.ui.control.Label
        ROIRadiusEditField     matlab.ui.control.NumericEditField
        ROIradiusLabel         matlab.ui.control.Label
        TSlicesEditField       matlab.ui.control.NumericEditField
        MaximumsigmapxLabel    matlab.ui.control.Label
        DetectSwitch           matlab.ui.control.Switch
        DetectSwitchLabel      matlab.ui.control.Label
        DatapathPanel          matlab.ui.container.Panel
        CalibrationunknowndataEditField  matlab.ui.control.EditField
        SelectpathButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        param = struct();
        data = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [currentFolder,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(currentFolder));
        end

        % Button pushed function: SelectpathButton
        function SelectpathButtonPushed(app, event)
            path = uigetdir;
            app.CalibrationunknowndataEditField.Value = path;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            main(app);
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            saveParamAuxFn(app);
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            loadParamAuxFn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MASUIFigure = uifigure('Visible', 'off');
            app.MASUIFigure.Name = 'MAS';
            app.DatapathPanel = uipanel(app.MASUIFigure);
            app.DatapathPanel.Title = '1. Data path';
            app.SelectpathButton = uibutton(app.DatapathPanel, 'push');
            app.SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpathButtonPushed, true);
            app.SelectpathButton.Text = 'Select path';
            app.CalibrationunknowndataEditField = uieditfield(app.DatapathPanel, 'text');
            app.DetectionandanalysisparametersPanel = uipanel(app.MASUIFigure);
            app.DetectionandanalysisparametersPanel.Title = '2. Detection and analysis parameters';
            app.DetectSwitchLabel = uilabel(app.DetectionandanalysisparametersPanel);
            app.DetectSwitchLabel.Text = 'Detect';
            app.DetectSwitch = uiswitch(app.DetectionandanalysisparametersPanel, 'slider');
            app.DetectSwitch.Items = {'Y', 'N'};
            app.DetectSwitch.Value = 'Y';
            app.MaximumsigmapxLabel = uilabel(app.DetectionandanalysisparametersPanel);
            app.MaximumsigmapxLabel.Text = 'T (time) slices';
            app.TSlicesEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.TSlicesEditField.Limits = [1 Inf];
            app.TSlicesEditField.Value = 13;
            app.ROIradiusLabel = uilabel(app.DetectionandanalysisparametersPanel);
            app.ROIradiusLabel.Text = 'ROI radius (px)';
            app.ROIRadiusEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.ROIRadiusEditField.Limits = [1 10];
            app.ROIRadiusEditField.Value = 2;
            app.AnalyzeLabel = uilabel(app.DetectionandanalysisparametersPanel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.AnalyzeSwitch = uiswitch(app.DetectionandanalysisparametersPanel, 'slider');
            app.AnalyzeSwitch.Items = {'Y', 'N'};
            app.AnalyzeSwitch.Value = 'Y';
            app.Labelingeffeciency = uilabel(app.DetectionandanalysisparametersPanel);
            app.Labelingeffeciency.Text = 'Reference stoichiometry';
            app.NumSubUnitsPerCalibComplexEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.NumSubUnitsPerCalibComplexEditField.Limits = [1 Inf];
            app.NumSubUnitsPerCalibComplexEditField.Value = 32;
            app.TimesliceminLabel = uilabel(app.DetectionandanalysisparametersPanel);
            app.TimesliceminLabel.Text = 'Time slice (min)';
            app.TimeSliceEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.TimeSliceEditField.Limits = [0 Inf];
            app.TimeSliceEditField.Value = 5;
            app.TimesliceminLabel_2 = uilabel(app.DetectionandanalysisparametersPanel);
            app.TimesliceminLabel_2.Text = 'Bin size (mol)';
            app.BinSizeEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.BinSizeEditField.Limits = [1 Inf];
            app.BinSizeEditField.Value = 50;
            app.MaximumsigmapxLabel_2 = uilabel(app.DetectionandanalysisparametersPanel);
            app.MaximumsigmapxLabel_2.Text = 'Z (depth) slices';
            app.ZSlicesEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.ZSlicesEditField.Limits = [1 Inf];
            app.ZSlicesEditField.Value = 8;
            app.MaximumsigmapxLabel_3 = uilabel(app.DetectionandanalysisparametersPanel);
            app.MaximumsigmapxLabel_3.Text = 'C (color) slices';
            app.CSlicesEditField = uieditfield(app.DetectionandanalysisparametersPanel, 'numeric');
            app.CSlicesEditField.Limits = [1 3];
            app.CSlicesEditField.Value = 3;
            app.RunButton = uibutton(app.MASUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.msgBox = uitextarea(app.MASUIFigure);
            app.SaveparametersButton = uibutton(app.MASUIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = {'Save parameters'; ''};
            app.LoadparametersButton = uibutton(app.MASUIFigure, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = {'Load parameters'; ''};
            % Show the figure after all components are created
            app.MASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAS_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MASUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MASUIFigure)
        end
    end
end